classdef bearingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        FileMenu  matlab.ui.container.Menu
        OpenMenu  matlab.ui.container.Menu
        ExitMenu  matlab.ui.container.Menu
        HelpMenu  matlab.ui.container.Menu
        UIAxes    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        fullFileName
        data
        faultFreq
        freq % Description
        spec
    end

    methods (Access = private)
        % https://www.mathworks.com/matlabcentral/answers/30238-gui-loading-a-mat-file
        function FileLoad(app)
            app.fullFileName = [];
            % Have user browse for a file, from a specified "starting folder."
            % For convenience in browsing, set a starting folder from which to browse.
            startingFolder = 'C:\Program Files\MATLAB';
            if ~exist(startingFolder, 'dir')
                % If that folder doesn't exist, just start in the current folder.
                startingFolder = pwd;
            end
            % Get the name of the mat file that the user wants to use.
            defaultFileName = fullfile(startingFolder, '*.mat');
            [baseFileName, folder] = uigetfile(defaultFileName, 'Select a mat file');
            if baseFileName == 0
                % User clicked the Cancel button.
                return;
            end
            app.fullFileName = fullfile(folder, baseFileName);
        end

        function calcBearing(app)
            [rd, pd, ca, ne, side] = NiceBearing;
            faultRates = ones(5, 1); %[cage, ball, outer, inner, shaft];
            for i = 1:4
                faultRates(i) = GetBearFreqRatio(rd, pd, ca, ne, i, side);
            end
            app.faultFreq = faultRates * app.data.bearing.rate;
            [env, dty] = envelope1(app.data.bearing.gs, 1/app.data.bearing.sr, 128, 2000, 4000);
            [app.spec, app.freq] = psde(env, 2048,1/dty, 1024);
        end
        
        % https://www.mathworks.com/help/releases/R2016b/matlab/creating_guis/graphics-support-in-app-designer.html
        function plotBearing(app)
            plot(app.UIAxes, app.freq, app.spec);
            ax = [xlim(app.UIAxes) ylim(app.UIAxes)];
            hold(app.UIAxes, 'on')
            for i = 1:5
                f = app.faultFreq(i);
                plot(app.UIAxes, [f f], ax(3:4),'LineWidth',2)
            end
            hold(app.UIAxes, 'off')
            legend(app.UIAxes, 'env', 'cage', 'ball', 'outer', 'inner', '1/rev')
            xlabel(app.UIAxes, 'Hz')
            ylabel(app.UIAxes, 'Gs')
            xlim(app.UIAxes, [0 200]);
            ylim(app.UIAxes, ax(3:4));
        end
    end


    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % https://www.mathworks.com/help/matlab/creating_guis/simple-app-designer-example-apps.html
            FileLoad(app);
            if app.fullFileName
                app.data = load(app.fullFileName);
                calcBearing(app);
                plotBearing(app);
            end
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bearing')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = bearingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end